classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Imager                        matlab.ui.Figure
        FilenameLabel                 matlab.ui.control.Label
        ImageFileL                    matlab.ui.control.EditField
        FilenameLabel_2               matlab.ui.control.Label
        ImageFileR                    matlab.ui.control.EditField
        Start                         matlab.ui.control.Button
        UIAxesL                       matlab.ui.control.UIAxes
        UIAxesR                       matlab.ui.control.UIAxes
        DisabledButton                matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        ShowOverlapButton             matlab.ui.control.Button
        ManualSelectionCheckBox       matlab.ui.control.CheckBox
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        NormalButton_2                matlab.ui.control.ToggleButton
        pwlButton                     matlab.ui.control.ToggleButton
        projectiveexperimentalButton  matlab.ui.control.ToggleButton
    end

% This app joins two images together. Enter the image file names (some examples are included in the file)
% in the left and right text fields labeled Name: below the axes. Choose the mode 
% (start with "Normal", avoid "projective" because it is very experimental) first,
% and press Start button. 
% 
% To use manual selection, click the radio button "Manual" and press Start. Search up documentation for "cpselect"
% for more information on how to use this tool. 
% 
% 
% IMPORTANT NOTES:
% 
% Only use the projective mode with the manual box selected! If not, the program will break. 
% Enter the filename in the "Filename" textbox (at the bottom, NOT the top), then click out for the program to load.
% To save the combined image, enter a desired filename at the top, then click "Save."
% To inspect usage of control points and overlapping regions, click "Show Overlap"


    properties (Access = public)
       ImageR
       ImageL
       filename
       panim
       manual
       rS
       lS
       tform
       panorama
    
       rW
       WB
       lP
       lW
       rP
       lP1
       figures
    end
    
    methods (Access = public)
        
        function getResults(app, pairL, pairR)
            app.lP = pairL;
            app.rP = pairR;
            app.CalculateButtonPushed;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ImageFileL, ImageFileR
        function NameEditFieldValueChanged(app, event)
            try
            if(event.Source == app.ImageFileL)
                value = app.ImageFileL.Value;
                app.ImageL = imread(value);
      
                imshow(app.ImageL, 'Parent',app.UIAxesL);
            elseif (event.Source == app.ImageFileR)
                value = app.ImageFileR.Value;
                app.ImageR = imread(value);
                imshow(app.ImageR, 'Parent',app.UIAxesR);
            end
            
            catch ME
                display(ME);
            end 
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            app.figures=cell(0, 0);
  
    
            if(size(app.ImageL) == 0)
                app.ImageL = imread('a.png');
                app.ImageR = imread('b.png');
                imshow(app.ImageL, 'Parent',app.UIAxesL);
                imshow(app.ImageR, 'Parent',app.UIAxesR);
            end
            
           
            if app.manual==true
               [app.lP app.rP] = cpselect(app.ImageL, app.ImageR, 'Wait', true);
            %   ds = load('pts', 'app');
            %   app.rP = ds.app.rP;
            %   app.lP = ds.app.lP;
                %reversed
            else
                Il = rgb2gray(app.ImageL);
                Ir = rgb2gray(app.ImageR);
                points = detectSURFFeatures(Il);    
                [features, points] = extractFeatures(Il, points);
                
                points2 = detectSURFFeatures(Ir, 'MetricThreshold',4000, 'NumOctaves', 2);
                [features2, points2] = extractFeatures(Ir, points2);
                
                ind = matchFeatures(features, features2, 'Unique', true);
                
                
                app.lP = points(ind(:, 1), :);
                app.rP = points2(ind(:, 2), :);
                app.lP = app.lP.Location;
                app.rP = app.rP.Location;
            end


            app.MethodButtonGroupSelectionChanged;
            app.CalculateButtonPushed;

            
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            app.lS = imref2d(size(app.ImageL));
            app.rS = imref2d(size(app.ImageR));
            
            display(1);
            try
            
            
            tf = app.tform;
            save('tform', 'tf');
            
            [xlim, ylim] = outputLimits(app.tform, app.lS.XWorldLimits, app.lS.YWorldLimits);
            [xlim2, ylim2]  = outputLimits(affine2d([1 0 0; 0 1 0; 0 0 1]), app.rS.XWorldLimits, app.rS.YWorldLimits);
            xlim(1) = min(xlim(1), xlim2(1));
            xlim(2) = max(xlim(2), xlim2(2));
            ylim(1) = min(ylim(1), ylim2(1));
            ylim(2) = max(ylim(2), ylim2(2));
            xlim= round(xlim);
            ylim = round(ylim);
            
            display(2);
            sz = [ylim(2)-ylim(1), xlim(2)-xlim(1)];
            app.panim = imref2d(sz, xlim, ylim);
          
            
            [app.rW, app.rS] = imwarp(app.ImageR, app.rS, affine2d([1 0 0; 0 1 0; 0 0 1]), 'OutputView', app.panim);
            [app.lW, app.lS] = imwarp(app.ImageL, app.lS, app.tform, 'OutputView', app.panim);
            display(3);
                app.lP1 = transformPointsForward(app.tform,  app.lP);
            catch ME
                display(ME);
            end 


            display(4);

            blender = vision.AlphaBlender('Operation', 'Binary mask','MaskSource', 'Input port');
            
            mask = app.ImageL(:, :, 1)+ app.ImageL(:, :, 2) + app.ImageL(:, :, 3);
            
            mask(mask(:, :)>0) = 1;
            mask = double(mask);
            mask = imerode(mask, strel('disk', 2));
            mask = imwarp(mask, app.tform, 'OutputView', app.panim);
            
            display(5);
            app.panorama = blender(app.rW, app.lW, mask);
           
            
           % waitbar(0.6, app.WB, 'Loading');
           
            app.figures{end+1} = figure;
            imshow(app.panorama, 'Parent', axes('Parent', app.figures{end}, 'Position', [0.1 0.1 0.9 0.9]));


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            try
            blender = vision.AlphaBlender('Operation', 'Binary mask', 'MaskSource', 'Input port');
            mask = app.ImageL(:, :, 1)+ app.ImageL(:, :, 2) + app.ImageL(:, :, 3);
            mask(mask(:, :)>0) = 1;
            mask = double(mask);
            mask = imerode(mask, strel('disk', 2));
            mask = imwarp(mask, app.tform, 'OutputView', app.panim);
            app.panorama = blender(app.rW, app.lW, mask);
            %imshow(lW);
           
            app.figures{end+1} = figure;
            axs = axes('Parent', app.figures{end}, 'Position', [0 0 1.0 0.95]);
            imshow(app.panorama, 'Parent', axs);
            title('290', 'Parent', axs);
       
            amask = app.panorama(:, :, 1)+ app.panorama(:, :, 2) + app.panorama(:, :, 3);
            amask(amask(:, :)==0)=0;
            amask(amask(:, :)>0)=1;
            amask=double(amask);    
           
            filepath = fileparts(mfilename('fullpath'));
            app.filename = strcat(filepath, '\' ,app.filename);
           
            imwrite(app.panorama, app.filename, 'Alpha', amask);
            
            app.figures{end+1} = figure;
            imshow(amask, axes('Parent', app.figures{end}, 'Position', [ 0 0 1 1]));
            
            catch ME
                display(ME);
            end 
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
            app.filename = value;
        end

        % Close request function: Imager
        function ImagerCloseRequest(app, event)


            delete(app)
            
        end

        % Button pushed function: ShowOverlapButton
        function ShowOverlapButtonPushed(app, event)
            
            try
            figure;
         
            cs = ['b' 'k' 'r' 'g' 'y' 'c' 'm' 'w'];
            imshowpair(app.rW, app.rS, app.lW, app.lS);
            hold on;
            for i=1:length(app.lP)
                scatter(app.rP(i, 1), app.rP(i, 2), 'o', 'MarkerFaceColor', cs(rem(i, 8)+1));
                scatter(app.lP1(i, 1), app.lP1(i, 2), 'o', 'MarkerFaceColor', cs(rem(i, 8)+1));

            end
            
            figure;
            cs = ['b' 'k' 'r' 'g' 'y' 'c' 'm' 'w'];
            imshow(app.ImageR);
            hold on;
            for i=1:length(app.rP)
                scatter(app.rP(i, 1), app.rP(i, 2), 'o', 'MarkerFaceColor', cs(rem(i, 8)+1));
                

            end
           
            figure,imshow(app.ImageL);
            hold on;
            for i=1:length(app.lP)
                scatter(app.lP(i, 1), app.lP(i, 2), 'o', 'MarkerFaceColor', cs(rem(i, 8)+1));
            end
            
            catch ME
                display(ME);
            end 
        end

        % Value changed function: ManualSelectionCheckBox
        function ManualSelectionCheckBoxValueChanged(app, event)
            value = app.ManualSelectionCheckBox.Value;
            app.manual = value;
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            try
                selectedButton = app.MethodButtonGroup.SelectedObject;
                display(selectedButton);
                if strcmp(selectedButton.Text, 'pwl')
                    app.tform = fitgeotrans(app.lP, app.rP, 'pwl');
                elseif strcmp(selectedButton.Text, 'projective (experimental)')
                    app.tform = fitgeotrans(app.lP, app.rP, 'projective');
                elseif strcmp(selectedButton.Text, 'Normal')
                    app.tform = estimateGeometricTransform(SURFPoints(app.lP), SURFPoints(app.rP),'similarity', 'Confidence', 99.99, 'MaxNumTrials', 2000);
                else
                    display('wtf');
                end 
            catch ME
                display(ME);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Imager = uifigure('Visible', 'off');
            app.Imager.Name = 'Imager';
            app.Imager.CloseRequestFcn = createCallbackFcn(app, @ImagerCloseRequest, true);
            app.FilenameLabel = uilabel(app.Imager);
            app.FilenameLabel.Text = 'Filename';
            app.ImageFileL = uieditfield(app.Imager, 'text');
            app.ImageFileL.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.FilenameLabel_2 = uilabel(app.Imager);
            app.FilenameLabel_2.Text = 'Filename';
            app.ImageFileR = uieditfield(app.Imager, 'text');
            app.ImageFileR.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.Start = uibutton(app.Imager, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Start';
            app.UIAxesL = uiaxes(app.Imager);
            title(app.UIAxesL, 'Title')
            xlabel(app.UIAxesL, 'X')
            ylabel(app.UIAxesL, 'Y')
            app.UIAxesL.XLim = [0 1];
            app.UIAxesL.XTick = [];
            app.UIAxesL.YTick = [];
            app.UIAxesR = uiaxes(app.Imager);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            app.UIAxesR.XTick = [];
            app.UIAxesR.YTick = [];
            app.DisabledButton = uibutton(app.Imager, 'push');
            app.DisabledButton.Visible = 'off';
            app.DisabledButton.Text = 'Disabled';
            app.SaveButton = uibutton(app.Imager, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.Imager);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.Imager, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.ShowOverlapButton = uibutton(app.Imager, 'push');
            app.ShowOverlapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOverlapButtonPushed, true);
            app.ShowOverlapButton.Text = 'Show Overlap';
            app.ManualSelectionCheckBox = uicheckbox(app.Imager);
            app.ManualSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualSelectionCheckBoxValueChanged, true);
            app.ManualSelectionCheckBox.Text = 'Manual Selection';
            app.MethodButtonGroup = uibuttongroup(app.Imager);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.NormalButton_2 = uitogglebutton(app.MethodButtonGroup);
            app.NormalButton_2.Text = 'Normal';
            app.NormalButton_2.Value = true;
            app.pwlButton = uitogglebutton(app.MethodButtonGroup);
            app.pwlButton.Text = 'pwl';
            app.projectiveexperimentalButton = uitogglebutton(app.MethodButtonGroup);
            app.projectiveexperimentalButton.Text = 'projective (experimental)';
            % Show the figure after all components are created
            app.Imager.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Imager)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Imager)
        end
    end
end